classdef VIONWAKE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup01                 matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        ParticleTrajectoriesTab    matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        Panel                      matlab.ui.container.Panel
        GaugeDebyeIon              matlab.ui.control.LinearGauge
        ElectronDebyeGaugeLabel    matlab.ui.control.Label
        GaugeDebyeElectron         matlab.ui.control.LinearGauge
        IonDebyeGaugeLabel         matlab.ui.control.Label
        SimulationControlPanel     matlab.ui.container.Panel
        NewseedCheckBox            matlab.ui.control.CheckBox
        ReplayButton               matlab.ui.control.Button
        PlayLamp                   matlab.ui.control.Lamp
        PlayButton                 matlab.ui.control.StateButton
        SimulationParametersPanel  matlab.ui.container.Panel
        PowerSpinner               matlab.ui.control.Spinner
        PowerSpinnerLabel          matlab.ui.control.Label
        PressureSpinner            matlab.ui.control.Spinner
        PressureSpinnerLabel       matlab.ui.control.Label
        GravitySpinner             matlab.ui.control.Spinner
        GravitySpinnerLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Simulation Parameters
        params

        %
        BLOCK_SIZE              = 32;

        % GAS
        GAS_TEMPERATURE         = 288.15;
        GAS_PRESSURE            = 10;
        ION_MASS                = 6.68e-26;
        IONIZATION_FRAC         = 1/1e6;
        ION_DEBYE               
        GAS_DENSITY             
        ION_DENSITY             
        
        % ELECTRON
        ELECTRON_TEMPERATURE    = 28815;
        ELECTRON_CHARGE         = -1.60e-19;
        ELECTRON_DENSITY
        ELECTRON_DEBYE       
        
        SHEATH_HEIGHT           = 10.6e-3;
        WAKE_CHARGE_PERCENT     = 0.3;
        WAKE_LENGTH             = 45e-6;
        CUTOFF_MULTIPLIER       = 6;

        NUM_PARTICLES           = 500;
        DUST_DENSITY            = 1.51 * 1e3;
        DUST_DIAMETER_MEAN      = 8.89e-6;
        DUST_RADIUS_MEAN
        DUST_DIAMETER_STD       = 0.01e-6;
        DUST_CHARGE_DENSITY_MEAN= 1000 * 1e6;
        DUST_CHARGE_DENSITY_STD = 20 * 1e6;
        
        % Constant
        COULOMB                 = 8.98e12;
        BOLTZMANN               = 1.38e-23;
        GRAVITY                 = 9.8;
        PERMITTIVITY            = 8.85418782e-12;

        CELL_CHARGE             = 1.6e2;
        CELL_RADIUS             = 0.6e-2;
        CELL_HEIGHT             = 1.2e-2;
        E_0                     = 3350;
        
  
        % Simulation variables
        dust;
        wake;
        plasma; 

        % Callbacks
        play                    = false;
        trace                   = false;
        record                  = false;
        tab                     = 'Main';
        setup                   = false;
        kernels

        % Timing globals
        TOTAL_TIME              = 0.0;
        firstFrame              = true;
        TIME                    = 0;
        drawRate                = 60;
        drawPeriod

        % CUDA KERNELS
        pcryAcc;
        pcryPos;

        % Axes objects
        dustAxes;
        wakeAxes;
        traceAxes;

        % OUTPUT AND INPUT FILES
        movFileID;
        fileParam               = 'parameters.txt';
    end
    
    methods (Access = private) 
        % CALCULATE PLASMA PARAMETERS======================================
        function calculatePlasmaParams(app)
            % CALCULATE NEUTRAL GAS DENSITY
            app.GAS_DENSITY = app.GAS_PRESSURE / (app.BOLTZMANN * app.GAS_TEMPERATURE);
            
            % CALCULATE ION AND ELECTRON DENSITIES
            app.ELECTRON_DENSITY = app.GAS_DENSITY * app.IONIZATION_FRAC;
            app.ION_DENSITY = app.GAS_DENSITY * app.IONIZATION_FRAC;

            % CALCULATE ION AND ELECTRON DEBYE LENGTHS
            app.ION_DEBYE = sqrt(app.PERMITTIVITY * app.BOLTZMANN * app.GAS_TEMPERATURE/...
                                    (app.ION_DENSITY * app.ELECTRON_CHARGE * app.ELECTRON_CHARGE));

            app.ELECTRON_DEBYE = sqrt(app.PERMITTIVITY * app.BOLTZMANN * app.ELECTRON_TEMPERATURE/...
                                    (app.ELECTRON_DENSITY * app.ELECTRON_CHARGE * app.ELECTRON_CHARGE));

            % DISPLAY CHANGES
            app.GaugeDebyeElectron.Value = app.ION_DEBYE * 1e6;
            app.GaugeDebyeIon.Value = app.ELECTRON_DEBYE * 1e6;
        end

        % CALCULATE TIMESTEP===============================================
        function calculateTimeStep(app)
            % Time to seed the random number generator
            app.TIME = convertTo(datetime('now'),'posixtime');

            % CALCULATE THE FORCES
            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryAcc,...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_RADIUS_MEAN,...
                                app.COULOMB,...
                                app.ION_DEBYE,...
                                app.ELECTRON_DEBYE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.CELL_RADIUS,...
                                app.CELL_CHARGE,...
                                app.CELL_HEIGHT,...
                                app.SHEATH_HEIGHT,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.GRAVITY,...
                                app.GAS_TEMPERATURE,...
                                app.GAS_PRESSURE,...
                                app.TIME_STEP,...
                                app.TIME,...
                                app.NUM_PARTICLES);

            % CALCULATE POSITIONS
            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryPos, ...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_CHARGE_DENSITY_MEAN,...
                                app.ELECTRON_CHARGE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.WAKE_LENGTH,...
                                app.ION_DEBYE,...
                                app.ELECTRON_DEBYE,...
                                app.TIME_STEP,...
                                app.TOTAL_TIME,...
                                app.NUM_PARTICLES);

            % COPY DATA BACK TO HOST
            app.dust.host.x     = gather(app.dust.x);
            app.dust.host.y     = gather(app.dust.y);
            app.dust.host.z     = gather(app.dust.z);
            app.dust.host.vx    = gather(app.dust.vx);
            app.dust.host.vy    = gather(app.dust.vy);
            app.dust.host.vz    = gather(app.dust.vz);
            app.dust.host.ax    = gather(app.dust.ax);
            app.dust.host.ay    = gather(app.dust.ay);
            app.dust.host.az    = gather(app.dust.az);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vdriadSetKernels(app);
            vdriadLoadParameters(app);
            
            %calculatePlasmaParams(app);
            vdriadUpdateParams(app);

            %generateParticles(app);
            [app.dust, app.params] = vdriadGenerateParticles(app.dust,app.params);

            vdriadFormatMain(app);
            vdriadDrawCell(app.UIAxes,app.params);
            %drawDust(app);
            vdriadDrawDust(app);
        end

        % Value changed function: PlayButton
        function Main(app, event)
            drawCount = 0;

            app.play = app.PlayButton.Value;
            
            % Change the color of the lamp
            if app.play == true
                app.PlayLamp.Color = 'green';
            else
                app.PlayLamp.Color = 'red';
            end

            % Keep the simulation running
            while app.play == true
                % Calculate one timestep
                % calculateTimeStep(app)
                app.dust = vdriadCalculateForces(app.dust,app.params,app.pcryAcc);
                app.dust = vdriadCalculatePositions(app.dust,app.params,app.pcryPos,app.TOTAL_TIME);
                
                % Copy data from the GPU to the host
                app.dust = vdriadMemCpy(app.dust,'DtoH');

                if drawCount == 0
                    vdriadDrawDust(app);
                end
                %vdriadDrawFiguresMain(app);

                drawnow limitrate

                % Increase counters
                app.TOTAL_TIME = app.TOTAL_TIME + app.params.TIME_STEP;
                drawCount = mod(drawCount+1,app.drawPeriod);
            end
        end

        % Value changing function: GravitySpinner
        function GravitySpinnerValueChanging(app, event)
            app.params.GRAVITY = event.Value;
        end

        % Value changing function: PressureSpinner
        function PressureSpinnerValueChanging(app, event)
            app.params.GAS_PRESSURE = event.Value;
            vdriadUpdateParams(app);
            %calculatePlasmaParams(app)
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            % Reset timer
            app.TOTAL_TIME = 0.0;

            if app.NewseedCheckBox.Value
                % Generate new seed for particles
                app.dust = vdriadGenerateParticles(app.dust,app.params);
            else
                % Retrieve and load initial conditions
                app.dust.h_x         = app.dust.x0;
                app.dust.h_y         = app.dust.y0;
                app.dust.h_z         = app.dust.z0;
                
                app.dust = vdriadMemCpy(app.dust,'HtoD','all');
            end

            % Draw first frame
            vdriadDrawDust(app)
        end

        % Value changed function: PowerSpinner
        function PowerSpinnerValueChanged(app, event)
            app.params.CELL_POWER = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GEC RF Reference Cell')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.GravitySpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySpinnerLabel.Text = 'Gravity ';
            app.GravitySpinner = uispinner(app.SimulationParametersPanel);
            app.GravitySpinner.ValueChangingFcn = createCallbackFcn(app, @GravitySpinnerValueChanging, true);
            app.PressureSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PressureSpinnerLabel.Text = 'Pressure ';
            app.PressureSpinner = uispinner(app.SimulationParametersPanel);
            app.PressureSpinner.ValueChangingFcn = createCallbackFcn(app, @PressureSpinnerValueChanging, true);
            app.PowerSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PowerSpinnerLabel.Text = 'Power ';
            app.PowerSpinner = uispinner(app.SimulationParametersPanel);
            app.PowerSpinner.ValueChangedFcn = createCallbackFcn(app, @PowerSpinnerValueChanged, true);
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.PlayButton = uibutton(app.SimulationControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @Main, true);
            app.PlayButton.Text = 'Play';
            app.PlayLamp = uilamp(app.SimulationControlPanel);
            app.ReplayButton = uibutton(app.SimulationControlPanel, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.NewseedCheckBox = uicheckbox(app.SimulationControlPanel);
            app.NewseedCheckBox.Text = 'New seed';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.IonDebyeGaugeLabel = uilabel(app.Panel);
            app.IonDebyeGaugeLabel.Text = 'Ion Debye';
            app.GaugeDebyeElectron = uigauge(app.Panel, 'linear');
            app.ElectronDebyeGaugeLabel = uilabel(app.Panel);
            app.ElectronDebyeGaugeLabel.Text = 'Electron Debye';
            app.GaugeDebyeIon = uigauge(app.Panel, 'linear');
            app.TabGroup01 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup01);
            app.Tab.Title = 'Tab';
            app.ParticleTrajectoriesTab = uitab(app.TabGroup01);
            app.ParticleTrajectoriesTab.Title = 'Particle Trajectories';
            app.UITable = uitable(app.ParticleTrajectoriesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIONWAKE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end